classdef learningquiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        NextButton     matlab.ui.control.Button
        OptionButton3  matlab.ui.control.Button
        OptionButton2  matlab.ui.control.Button
        QuestionLabel  matlab.ui.control.Label
        OptionButton1  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentQuestionIndex = 1
        Scores = struct('Visual', 0, 'Auditory', 0, 'Kinesthetic', 0)
        QuizData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Define quiz questions and options
           app.QuizData(1).question = "How do you best remember information?";
           app.QuizData(1).options = {"By seeing images", "By hearing explanations", "By doing activities"};
           app.QuizData(1).type = {"Visual", "Auditory", "Kinesthetic"};
      
           app.QuizData(2).question = "What helps you concentrate?";
           app.QuizData(2).options = {"Reading notes", "Listening to music", "Taking walks"};
           app.QuizData(2).type = {"Visual", "Auditory", "Kinesthetic"};
      
           app.QuizData(3).question = "What's your ideal study tool?";
           app.QuizData(3).options = {"Flashcards", "Audio recordings", "Hands-on tools"};
           app.QuizData(3).type = {"Visual", "Auditory", "Kinesthetic"};
      
           % Initialize score tracking
           app.Scores.Visual = 0;
           app.Scores.Auditory = 0;
           app.Scores.Kinesthetic = 0;
      
           app.CurrentQuestionIndex = 1;
      
           % Display first question
           displayQuestion(app);
        end
       
        function updateScores(app, selectedIndex)
            type = app.QuizData(app.CurrentQuestionIndex).type{selectedIndex};
            app.Scores.(type) = app.Scores.(type) + 1
            
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
            
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app);
            else
                displayQuestion(app);
            end

        end

        % Button pushed function: OptionButton1
        function OptionButton1Pushed(app, event)
             updateScores(app, 1);

        end

        % Button pushed function: OptionButton2
        function OptionButton2Pushed(app, event)
            updateScores(app, 2);

        end

        % Button pushed function: OptionButton3
        function OptionButton3Pushed(app, event)
            updateScores(app, 3);
        end

        function displayQuestion(app)
            q = app.QuizData(app.CurrentQuestionIndex);

            app.QuestionLabel.Text = q.question;

            app.OptionButton1.Text = q.options{1};
            app.OptionButton2.Text = q.options{2};
            app.OptionButton3.Text = q.options{3};

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
           
           % Move to next question
           app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
               app.ButtonGroup.SelectedObject = []; % Clear selection
           end
        end

        function showResults(app)
            [~, maxType] = max(struct2array(app.Scores));
            types = fieldnames(app.Scores);
            dominantType = types{maxType};
            
            msg = sprintf("Your dominant learning style is: %s", dominantType);
            uialert(app.UIFigure, msg, 'Results');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionButton1 = uibutton(app.UIFigure, 'push');
            app.OptionButton1.ButtonPushedFcn = createCallbackFcn(app, @OptionButton1Pushed, true);
            app.OptionButton1.Text = 'Option Button 1';
            app.QuestionLabel = uilabel(app.UIFigure);
            app.QuestionLabel.Text = 'Question Label';
            app.OptionButton2 = uibutton(app.UIFigure, 'push');
            app.OptionButton2.ButtonPushedFcn = createCallbackFcn(app, @OptionButton2Pushed, true);
            app.OptionButton2.Text = 'Option Button 2';
            app.OptionButton3 = uibutton(app.UIFigure, 'push');
            app.OptionButton3.ButtonPushedFcn = createCallbackFcn(app, @OptionButton3Pushed, true);
            app.OptionButton3.Text = 'Option Button 3';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = learningquiz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end